classdef NA22_Marx_app_3cell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StopHeartButton           matlab.ui.control.Button
        StartHeartButton          matlab.ui.control.Button
        CellsActiveLabel          matlab.ui.control.Label
        Cell3CheckBox             matlab.ui.control.CheckBox
        Cell2CheckBox             matlab.ui.control.CheckBox
        Cell1CheckBox             matlab.ui.control.CheckBox
        DisconnectSerialButton    matlab.ui.control.Button
        ConnectSerialButton       matlab.ui.control.Button
        RefreshCOMButton          matlab.ui.control.Button
        COMPortsDropDown          matlab.ui.control.DropDown
        COMPortsDropDownLabel     matlab.ui.control.Label
        RefreshButton             matlab.ui.control.Button
        Cell3Panel                matlab.ui.container.Panel
        ControlRB_3               matlab.ui.control.Label
        ControlLamp_3             matlab.ui.control.Lamp
        ControlStatusLabel_3      matlab.ui.control.Label
        TS3Label_3                matlab.ui.control.Label
        VActLabel_3               matlab.ui.control.Label
        HVActLabel_3              matlab.ui.control.Label
        Label_24                  matlab.ui.control.Label
        VLabel_9                  matlab.ui.control.Label
        VLabel_8                  matlab.ui.control.Label
        Label_23                  matlab.ui.control.Label
        BVAVLabel_3               matlab.ui.control.Label
        HVAVLabel_3               matlab.ui.control.Label
        CountsLabel_12            matlab.ui.control.Label
        TSCLabel_3                matlab.ui.control.Label
        CountsLabel_11            matlab.ui.control.Label
        BVACLabel_3               matlab.ui.control.Label
        CountsLabel_10            matlab.ui.control.Label
        HVACLabel_3               matlab.ui.control.Label
        HVSetEditField_3          matlab.ui.control.NumericEditField
        HVSetEditField_3Label     matlab.ui.control.Label
        CountsLabel_9             matlab.ui.control.Label
        HVSCLabel_3               matlab.ui.control.Label
        VLabel_7                  matlab.ui.control.Label
        ChargeDisableCheckBox_3   matlab.ui.control.CheckBox
        TriggerDisableCheckBox_3  matlab.ui.control.CheckBox
        Cell2Panel                matlab.ui.container.Panel
        ControlRB_2               matlab.ui.control.Label
        TS3Label_2                matlab.ui.control.Label
        VActLabel_2               matlab.ui.control.Label
        HVActLabel_2              matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        VLabel_6                  matlab.ui.control.Label
        VLabel_5                  matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        BVAVLabel_2               matlab.ui.control.Label
        HVAVLabel_2               matlab.ui.control.Label
        CountsLabel_8             matlab.ui.control.Label
        TSCLabel_2                matlab.ui.control.Label
        CountsLabel_7             matlab.ui.control.Label
        BVACLabel_2               matlab.ui.control.Label
        CountsLabel_6             matlab.ui.control.Label
        HVACLabel_2               matlab.ui.control.Label
        HVSetEditField_2          matlab.ui.control.NumericEditField
        HVSetEditField_2Label     matlab.ui.control.Label
        CountsLabel_5             matlab.ui.control.Label
        HVSCLabel_2               matlab.ui.control.Label
        VLabel_4                  matlab.ui.control.Label
        ControlLamp_2             matlab.ui.control.Lamp
        ControlStatusLabel_2      matlab.ui.control.Label
        ChargeDisableCheckBox_2   matlab.ui.control.CheckBox
        TriggerDisableCheckBox_2  matlab.ui.control.CheckBox
        Cell1Panel                matlab.ui.container.Panel
        ControlRB_1               matlab.ui.control.Label
        TS3Label                  matlab.ui.control.Label
        VActLabel                 matlab.ui.control.Label
        HVActLabel                matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        VLabel_3                  matlab.ui.control.Label
        VLabel_2                  matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        BVAVLabel_1               matlab.ui.control.Label
        HVAVLabel_1               matlab.ui.control.Label
        CountsLabel_4             matlab.ui.control.Label
        TSCLabel_1                matlab.ui.control.Label
        CountsLabel_3             matlab.ui.control.Label
        BVACLabel_1               matlab.ui.control.Label
        CountsLabel_2             matlab.ui.control.Label
        HVACLabel_1               matlab.ui.control.Label
        HVSetEditField_1          matlab.ui.control.NumericEditField
        HVSetEditFieldLabel       matlab.ui.control.Label
        CountsLabel               matlab.ui.control.Label
        HVSCLabel_1               matlab.ui.control.Label
        VLabel_10                 matlab.ui.control.Label
        ControlLamp_1             matlab.ui.control.Lamp
        ControlStatusLabel        matlab.ui.control.Label
        ChargeDisableCheckBox     matlab.ui.control.CheckBox
        TriggerDisableCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        MyTimer = 0;
    end
    
    methods (Access = private)
        
        function TimerCallback(app, obj, event, string_arg)
            RefreshButtonPushed(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            ttyList={};
            portOrder =[];
            [stat, result] = system('mode');
            % identify the COM ports in the output string and format in cell array
            portList = regexp(result,'COM\d+:','match');
            k=0;
            for p = portList
                k=k+1;
                portNum = regexp(p{1},'\d+','match');
                portName = regexp(p{1},'COM\d+','match');
                ttyList = [ttyList; {portNum{1}, portName{1}}];
                portOrder = [portOrder str2num(portNum{1})];
            end
            % return the list sorted with largest number first
            [p2,i]=sort(portOrder,2,'descend');
            a = ttyList(i,:);
            k=size(a);
            if k(1)>0
                for n=1:k(1)
                    a(n,2);
                end
                app.COMPortsDropDown.Items=a(:,2);
            else
                app.COMPortsDropDown.Items='None';
            end
        end

        % Button pushed function: ConnectSerialButton
        function ConnectSerialButtonPushed(app, event)
            global s;
            try
                s = serialport(app.COMPortsDropDown.Value,38400);
% % % % % %    [ALEX NOTE] IS TERMINATOR AN ISSUE? ANY TERMINATOR ON CURRENT MCU CODE??
% % % % % %    readline() expects the device terminator to end dataread...
%                 configureTerminator(s,'LF'); %set comma as terminator (44) 
                configureTerminator(s,44,"LF");
            catch 
            end
            pause(1);
        end

        % Button pushed function: DisconnectSerialButton
        function DisconnectSerialButtonPushed(app, event)
            clear global;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            global s;

            % Convert user setpoint voltage to appropriate counts value.
            function HVcounts = HVsetpoint2counts(setPoint) 
                cnts=round(-6.04341e-11*setPoint^5+ ...
                    1.58494E-07*setPoint^4 +-0.00016449*setPoint^3 + ...
                    0.085786488*setPoint^2 +-23.51670929*setPoint + ...
                    2987.713154);
                if cnts>1022
                    cnts=1022;
                elseif cnts<10 %%[ASN] What does 10 correspond to? 770V?
                    cnts=10;
                end 
                HVcounts = cnts;
            end

            for n=1:3
                % Yellow lamp before control feedback received
                eval(strcat('app.ControlLamp_',num2str(n),'.Color = [1,1,0.07];'))
            end

            %ADD LOOP OVER ALL CELLS
% % % % % % %       [ALEX NOTE] ONLY SUPPORTS CELL A READBACKS ATM?
% % % % % % %       identical control setting to all cells but only
% % % % % % %       supports Cell A for all else
            for n=1:2 %double tap              [ASN] whatfor
                controlA = 0b00000000;
                controlB = 0b00000000;
                controlC = 0b00000000;
                if (app.TriggerDisableCheckBox.Value)
                    controlA=bitset(controlA,1); %bit 0 in arduino, 1 in matlab
                end
                if (app.ChargeDisableCheckBox.Value)
                    controlA=bitset(controlA,2); %bit 1 in arduino, 2 in matlab
                end
                if (app.TriggerDisableCheckBox_2.Value)
                    controlB=bitset(controlB,1); %bit 0 in arduino, 1 in matlab
                end
                if (app.ChargeDisableCheckBox_2.Value)
                    controlB=bitset(controlB,2); %bit 1 in arduino, 2 in matlab
                end
                if (app.TriggerDisableCheckBox_3.Value)
                    controlC=bitset(controlC,1); %bit 0 in arduino, 1 in matlab
                end
                if (app.ChargeDisableCheckBox_3.Value)
                    controlC=bitset(controlC,2); %bit 1 in arduino, 2 in matlab
                end

                write(s,['!C 000 ' num2str(controlA,'%03d') ' ' num2str(controlB,'%03d') ' ' num2str(controlC,'%03d') ','],"char");                  %end byte
                echo =      char(readline(s));

                %% Verify control state feedback
                %% DO NOT RENAME DYNAMIC DISPLAY FIELDS!! CODE WILL BREAK!
                parse = strsplit(echo);
                if parse == "" % Check for timeout
                    disp('Error: Serial timeout on !C return line, refresh aborted.')
                    for o = 1:3
                        eval(strcat('app.ControlLamp_',num2str(o),'.Color = [1,0,0];'))
                    end
                    break
                else % Compare control readback string to input string
                    for o = 1:3
                        eval(strcat('app.ControlRB_',num2str(o),'.Text = "',parse{o+2}(1:3),'";'))
                        if(strcmp(num2str(controlA,'%03d'),parse{o+2}(1:3)))
                            eval(strcat('app.ControlLamp_',num2str(o),'.Color = [0,1,0];'))
                        else
                            eval(strcat('app.ControlLamp_',num2str(o),'.Color = [1,0,0];'))
                        end
                    end
                end
                

                %% Convert Vdes from Volts to counts
                v1counts = HVsetpoint2counts(app.HVSetEditField_1.Value);
                v2counts = HVsetpoint2counts(app.HVSetEditField_2.Value);
                v3counts = HVsetpoint2counts(app.HVSetEditField_3.Value);
                
                app.HVSCLabel_1.Text = sprintf('%04d',(v1counts));
                app.HVSCLabel_2.Text = sprintf('%04d',(v2counts));
                app.HVSCLabel_3.Text = sprintf('%04d',(v3counts));
                write(s,['!H 000 ' num2str(v1counts,'%04d') ' ' ...
                    num2str(v2counts,'%04d') ' ' ...
                    num2str(v3counts,'%04d') ','],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                if parse == "" % Check for timeout
                    disp('Error: Serial timeout on !H return line, refresh aborted.')
                    break
                else % Compare control readback string to input string
                    for o = 1:3
                        HV_act = sprintf('%04d',(str2num(parse{o+2}(1:4))));
                        HV_act_volts = num2str(str2num(HV_act)/.418,'%4.1f');
                        eval(strcat('app.HVACLabel_',num2str(o),'.Text = "',HV_act,'";'))
                        eval(strcat('app.HVAVLabel_',num2str(o),'.Text = "',HV_act_volts,'";'))
                    end
                end
                
                write(s,['!B 000 0000 0000 0000,'],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                if parse == "" % Check for timeout
                    disp('Error: Serial timeout on !B return line, refresh aborted.')
                    break
                else % Compare control readback string to input string
                    for o = 1:3
                        BV_act = sprintf('%04d',(str2num(parse{o+2}(1:4))));
                        BV_act_volts = num2str(str2num(BV_act)*5/2^10*6,'%4.1f');
                        eval(strcat('app.BVACLabel_',num2str(o),'.Text = "',BV_act,'";'))
                        eval(strcat('app.BVAVLabel_',num2str(o),'.Text = "',BV_act_volts,'";'))
                    end
                end
                
                write(s,['!T 000 0000 0000 0000,'],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                if parse == "" % Check for timeout
                    disp('Error: Serial timeout on !T return line, refresh aborted.')
                    break
                else % Compare control readback string to input string
                    for o = 1:3
                        TS_act = sprintf('%04d',(str2num(parse{o+2}(1:4))));
                        eval(strcat('app.TSCLabel_',num2str(o),'.Text = "',TS_act,'";'))
                    end
                end
                
            end


        end

        % Button pushed function: StartHeartButton
        function StartHeartButtonPushed(app, event)
            app.MyTimer = timer('Period',1,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
            start(app.MyTimer);
            app.StartHeartButton.BackgroundColor = [0,1,0];
        end

        % Button pushed function: StopHeartButton
        function StopHeartButtonPushed(app, event)
            stop(app.MyTimer);
            app.StartHeartButton.BackgroundColor = [.96,.96,.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Cell1Panel = uipanel(app.UIFigure);
            app.Cell1Panel.Title = 'Cell 1';
            app.TriggerDisableCheckBox = uicheckbox(app.Cell1Panel);
            app.TriggerDisableCheckBox.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox.Value = true;
            app.ChargeDisableCheckBox = uicheckbox(app.Cell1Panel);
            app.ChargeDisableCheckBox.Text = 'Charge Disable';
            app.ChargeDisableCheckBox.Value = true;
            app.ControlStatusLabel = uilabel(app.Cell1Panel);
            app.ControlStatusLabel.Text = 'Control Status';
            app.ControlLamp_1 = uilamp(app.Cell1Panel);
            app.VLabel_10 = uilabel(app.Cell1Panel);
            app.VLabel_10.Text = '(V)';
            app.HVSCLabel_1 = uilabel(app.Cell1Panel);
            app.HVSCLabel_1.Text = '.';
            app.CountsLabel = uilabel(app.Cell1Panel);
            app.CountsLabel.Text = 'Counts';
            app.HVSetEditFieldLabel = uilabel(app.Cell1Panel);
            app.HVSetEditFieldLabel.Text = 'HV Set';
            app.HVSetEditField_1 = uieditfield(app.Cell1Panel, 'numeric');
            app.HVSetEditField_1.Limits = [135 756];
            app.HVSetEditField_1.Value = 135;
            app.HVACLabel_1 = uilabel(app.Cell1Panel);
            app.HVACLabel_1.Text = '.';
            app.CountsLabel_2 = uilabel(app.Cell1Panel);
            app.CountsLabel_2.Text = 'Counts';
            app.BVACLabel_1 = uilabel(app.Cell1Panel);
            app.BVACLabel_1.Text = '.';
            app.CountsLabel_3 = uilabel(app.Cell1Panel);
            app.CountsLabel_3.Text = 'Counts';
            app.TSCLabel_1 = uilabel(app.Cell1Panel);
            app.TSCLabel_1.Text = '.';
            app.CountsLabel_4 = uilabel(app.Cell1Panel);
            app.CountsLabel_4.Text = 'Counts';
            app.HVAVLabel_1 = uilabel(app.Cell1Panel);
            app.HVAVLabel_1.Text = '.';
            app.BVAVLabel_1 = uilabel(app.Cell1Panel);
            app.BVAVLabel_1.Text = '.';
            app.Label_7 = uilabel(app.Cell1Panel);
            app.Label_7.Text = '.';
            app.VLabel_2 = uilabel(app.Cell1Panel);
            app.VLabel_2.Text = '(V)';
            app.VLabel_3 = uilabel(app.Cell1Panel);
            app.VLabel_3.Text = '(V)';
            app.Label_8 = uilabel(app.Cell1Panel);
            app.Label_8.Text = '(?)';
            app.HVActLabel = uilabel(app.Cell1Panel);
            app.HVActLabel.Text = 'HV Act';
            app.VActLabel = uilabel(app.Cell1Panel);
            app.VActLabel.Text = '14V Act';
            app.TS3Label = uilabel(app.Cell1Panel);
            app.TS3Label.Text = 'TS3';
            app.ControlRB_1 = uilabel(app.Cell1Panel);
            app.ControlRB_1.Text = '???';
            app.Cell2Panel = uipanel(app.UIFigure);
            app.Cell2Panel.Title = 'Cell 2';
            app.TriggerDisableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.TriggerDisableCheckBox_2.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox_2.Value = true;
            app.ChargeDisableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.ChargeDisableCheckBox_2.Text = 'Charge Disable';
            app.ChargeDisableCheckBox_2.Value = true;
            app.ControlStatusLabel_2 = uilabel(app.Cell2Panel);
            app.ControlStatusLabel_2.Text = 'Control Status';
            app.ControlLamp_2 = uilamp(app.Cell2Panel);
            app.VLabel_4 = uilabel(app.Cell2Panel);
            app.VLabel_4.Text = '(V)';
            app.HVSCLabel_2 = uilabel(app.Cell2Panel);
            app.HVSCLabel_2.Text = '.';
            app.CountsLabel_5 = uilabel(app.Cell2Panel);
            app.CountsLabel_5.Text = 'Counts';
            app.HVSetEditField_2Label = uilabel(app.Cell2Panel);
            app.HVSetEditField_2Label.Text = 'HV Set';
            app.HVSetEditField_2 = uieditfield(app.Cell2Panel, 'numeric');
            app.HVSetEditField_2.Limits = [135 756];
            app.HVSetEditField_2.Value = 135;
            app.HVACLabel_2 = uilabel(app.Cell2Panel);
            app.HVACLabel_2.Text = '.';
            app.CountsLabel_6 = uilabel(app.Cell2Panel);
            app.CountsLabel_6.Text = 'Counts';
            app.BVACLabel_2 = uilabel(app.Cell2Panel);
            app.BVACLabel_2.Text = '.';
            app.CountsLabel_7 = uilabel(app.Cell2Panel);
            app.CountsLabel_7.Text = 'Counts';
            app.TSCLabel_2 = uilabel(app.Cell2Panel);
            app.TSCLabel_2.Text = '.';
            app.CountsLabel_8 = uilabel(app.Cell2Panel);
            app.CountsLabel_8.Text = 'Counts';
            app.HVAVLabel_2 = uilabel(app.Cell2Panel);
            app.HVAVLabel_2.Text = '.';
            app.BVAVLabel_2 = uilabel(app.Cell2Panel);
            app.BVAVLabel_2.Text = '.';
            app.Label_15 = uilabel(app.Cell2Panel);
            app.Label_15.Text = '.';
            app.VLabel_5 = uilabel(app.Cell2Panel);
            app.VLabel_5.Text = '(V)';
            app.VLabel_6 = uilabel(app.Cell2Panel);
            app.VLabel_6.Text = '(V)';
            app.Label_16 = uilabel(app.Cell2Panel);
            app.Label_16.Text = '(?)';
            app.HVActLabel_2 = uilabel(app.Cell2Panel);
            app.HVActLabel_2.Text = 'HV Act';
            app.VActLabel_2 = uilabel(app.Cell2Panel);
            app.VActLabel_2.Text = '14V Act';
            app.TS3Label_2 = uilabel(app.Cell2Panel);
            app.TS3Label_2.Text = 'TS3';
            app.ControlRB_2 = uilabel(app.Cell2Panel);
            app.ControlRB_2.Text = '???';
            app.Cell3Panel = uipanel(app.UIFigure);
            app.Cell3Panel.Title = 'Cell 3';
            app.TriggerDisableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.TriggerDisableCheckBox_3.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox_3.Value = true;
            app.ChargeDisableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.ChargeDisableCheckBox_3.Text = 'Charge Disable';
            app.ChargeDisableCheckBox_3.Value = true;
            app.VLabel_7 = uilabel(app.Cell3Panel);
            app.VLabel_7.Text = '(V)';
            app.HVSCLabel_3 = uilabel(app.Cell3Panel);
            app.HVSCLabel_3.Text = '.';
            app.CountsLabel_9 = uilabel(app.Cell3Panel);
            app.CountsLabel_9.Text = 'Counts';
            app.HVSetEditField_3Label = uilabel(app.Cell3Panel);
            app.HVSetEditField_3Label.Text = 'HV Set';
            app.HVSetEditField_3 = uieditfield(app.Cell3Panel, 'numeric');
            app.HVSetEditField_3.Limits = [135 756];
            app.HVSetEditField_3.Value = 135;
            app.HVACLabel_3 = uilabel(app.Cell3Panel);
            app.HVACLabel_3.Text = '.';
            app.CountsLabel_10 = uilabel(app.Cell3Panel);
            app.CountsLabel_10.Text = 'Counts';
            app.BVACLabel_3 = uilabel(app.Cell3Panel);
            app.BVACLabel_3.Text = '.';
            app.CountsLabel_11 = uilabel(app.Cell3Panel);
            app.CountsLabel_11.Text = 'Counts';
            app.TSCLabel_3 = uilabel(app.Cell3Panel);
            app.TSCLabel_3.Text = '.';
            app.CountsLabel_12 = uilabel(app.Cell3Panel);
            app.CountsLabel_12.Text = 'Counts';
            app.HVAVLabel_3 = uilabel(app.Cell3Panel);
            app.HVAVLabel_3.Text = '.';
            app.BVAVLabel_3 = uilabel(app.Cell3Panel);
            app.BVAVLabel_3.Text = '.';
            app.Label_23 = uilabel(app.Cell3Panel);
            app.Label_23.Text = '.';
            app.VLabel_8 = uilabel(app.Cell3Panel);
            app.VLabel_8.Text = '(V)';
            app.VLabel_9 = uilabel(app.Cell3Panel);
            app.VLabel_9.Text = '(V)';
            app.Label_24 = uilabel(app.Cell3Panel);
            app.Label_24.Text = '(?)';
            app.HVActLabel_3 = uilabel(app.Cell3Panel);
            app.HVActLabel_3.Text = 'HV Act';
            app.VActLabel_3 = uilabel(app.Cell3Panel);
            app.VActLabel_3.Text = '14V Act';
            app.TS3Label_3 = uilabel(app.Cell3Panel);
            app.TS3Label_3.Text = 'TS3';
            app.ControlStatusLabel_3 = uilabel(app.Cell3Panel);
            app.ControlStatusLabel_3.Text = 'Control Status';
            app.ControlLamp_3 = uilamp(app.Cell3Panel);
            app.ControlRB_3 = uilabel(app.Cell3Panel);
            app.ControlRB_3.Text = '???';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.COMPortsDropDownLabel = uilabel(app.UIFigure);
            app.COMPortsDropDownLabel.Text = 'COM Ports';
            app.COMPortsDropDown = uidropdown(app.UIFigure);
            app.COMPortsDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.RefreshCOMButton = uibutton(app.UIFigure, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.ConnectSerialButton = uibutton(app.UIFigure, 'push');
            app.ConnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialButtonPushed, true);
            app.ConnectSerialButton.Text = 'Connect Serial';
            app.DisconnectSerialButton = uibutton(app.UIFigure, 'push');
            app.DisconnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSerialButtonPushed, true);
            app.DisconnectSerialButton.Text = 'Disconnect Serial';
            app.Cell1CheckBox = uicheckbox(app.UIFigure);
            app.Cell1CheckBox.Enable = 'off';
            app.Cell1CheckBox.Text = 'Cell 1';
            app.Cell1CheckBox.Value = true;
            app.Cell2CheckBox = uicheckbox(app.UIFigure);
            app.Cell2CheckBox.Enable = 'off';
            app.Cell2CheckBox.Text = 'Cell 2';
            app.Cell2CheckBox.Value = true;
            app.Cell3CheckBox = uicheckbox(app.UIFigure);
            app.Cell3CheckBox.Enable = 'off';
            app.Cell3CheckBox.Text = 'Cell 3';
            app.Cell3CheckBox.Value = true;
            app.CellsActiveLabel = uilabel(app.UIFigure);
            app.CellsActiveLabel.Text = 'Cells Active';
            app.StartHeartButton = uibutton(app.UIFigure, 'push');
            app.StartHeartButton.ButtonPushedFcn = createCallbackFcn(app, @StartHeartButtonPushed, true);
            app.StartHeartButton.Text = 'Start Heart';
            app.StopHeartButton = uibutton(app.UIFigure, 'push');
            app.StopHeartButton.ButtonPushedFcn = createCallbackFcn(app, @StopHeartButtonPushed, true);
            app.StopHeartButton.Text = 'Stop Heart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NA22_Marx_app_3cell

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end